classdef Function_Generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        EnterAmplitudeButton     matlab.ui.control.Button
        EnterTimePeriodButton    matlab.ui.control.Button
        EnterHarmonicsButton     matlab.ui.control.Button
        FullWaveRectifiedButton  matlab.ui.control.Button
        HalfWaveRectifiedButton  matlab.ui.control.Button
        RectangularWaveButton    matlab.ui.control.Button
        SquareWaveButton         matlab.ui.control.Button
        SawToothButton           matlab.ui.control.Button
        TriangularWaveButton     matlab.ui.control.Button
        ImpulseTrainButton       matlab.ui.control.Button
        SelecttheWaveformLabel   matlab.ui.control.Label
        EntertheParametersLabel  matlab.ui.control.Label
        CopiesofSignalButton     matlab.ui.control.Button
        FunctionGeneratorUsingFourierSeriesCoefficientLabel  matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        Label                    matlab.ui.control.Label
        FunctionGeneratorButton  matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Amp     % Amplitude of Signal
        T_0     % Time Period of Signal
        kk      % Harmonics
        cc      % Copies of Signal
        t       % time axis
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterAmplitudeButton
        function EnterAmplitudeButtonPushed(app, event)
            app.Amp =str2double(inputdlg('Enter the Amplitude of Signal = '));
        end

        % Button pushed function: SquareWaveButton
        function SquareWaveButtonPushed(app, event)
            app.t= -app.cc*app.T_0:0.01:app.cc*app.T_0;
            yt = 0;   % Dc value in Fourier Series
            for k = -app.kk:app.kk
                if k == 0    % For DC Value
                    yt = yt;
                elseif mod(k,2)~=0    % For Odd Harmonics
                    yt = yt + (-1i*2*app.Amp)./(pi*k).*exp(k*1i*2*pi*app.t/app.T_0);
                else         % For Even Harmonics
                    yt = yt + 0;    
                end
            end
            plot(app.UIAxes,app.t,yt);
            xlabel(app.UIAxes,'Time (sec)');  ylabel(app.UIAxes,'x(t)');
            title(app.UIAxes,'Square Wave')
        end

        % Button pushed function: EnterTimePeriodButton
        function EnterTimePeriodButtonPushed(app, event)
            app.T_0 =str2double(inputdlg('Enter the Time Period of Signal = '));
        end

        % Button pushed function: EnterHarmonicsButton
        function EnterHarmonicsButtonPushed(app, event)
            app.kk =str2double(inputdlg('Enter the Number of harmonics for Fourier Series = '));
        end

        % Button pushed function: CopiesofSignalButton
        function CopiesofSignalButtonPushed(app, event)
            app.cc =str2double(inputdlg('Enter the Copies of Signal to plot = '));
        end

        % Button pushed function: FullWaveRectifiedButton
        function FullWaveRectifiedButtonPushed(app, event)
            app.t= -app.cc*app.T_0:0.01:app.cc*app.T_0;
            yt = 2*app.Amp/pi;   % Dc value in Fourier Series
            for k = -app.kk:app.kk
                if k == 0    % For DC Value
                    yt = yt;
                else       % For Even and Odd Harmonics
                    yt = yt + (-2*app.Amp/(pi*((4*k^2)-1))).*exp(k*1i*2*pi*app.t/app.T_0);
                end
            end
            plot(app.UIAxes,app.t,yt);
            xlabel(app.UIAxes,'Time (sec)');  ylabel(app.UIAxes,'x(t)');
            title(app.UIAxes,'Full-Wave Rectified')
        end

        % Button pushed function: HalfWaveRectifiedButton
        function HalfWaveRectifiedButtonPushed(app, event)
            app.t= -app.cc*app.T_0:0.01:app.cc*app.T_0;
            yt = app.Amp/pi;   % Dc value in Fourier Series
            for k = -app.kk:app.kk
                if k == 0     % For DC Value
                    yt = yt;
                elseif mod(k,2)~=0     
                    if k==1 || k==-1      % For First Harmonic 
                        yt = yt +(-k*1i*app.Amp/4).*exp(k*1i*2*pi*app.t/app.T_0);
                    else
                        yt = yt + 0;    % For Odd Harmonics
                    end
                else            % For Even Harmonics
                    yt = yt + (-app.Amp/(pi*((k^2)-1))).*exp(k*1i*2*pi*app.t/app.T_0);
                end
            end
            plot(app.UIAxes,app.t,yt);
            xlabel(app.UIAxes,'Time (sec)');  ylabel(app.UIAxes,'x(t)');
            title(app.UIAxes,'Half-Wave Rectified')
        end

        % Button pushed function: RectangularWaveButton
        function RectangularWaveButtonPushed(app, event)
            yt = app.Amp/2;    % Dc value in Fourier Series
            for k = -app.kk:app.kk
                if k == 0         % For DC Value
                    yt = yt;
                else       % For Even and Odd Harmonics
                    yt = yt + (app.Amp/2).*(sin(pi*k/2)./(pi*k/2)).*exp(k*1i*2*pi*app.t/app.T_0);
                end
            end
            plot(app.UIAxes,app.t,yt);
            xlabel(app.UIAxes,'Time (sec)');  ylabel(app.UIAxes,'x(t)');
            title(app.UIAxes,'Rectangular Wave')
        end

        % Button pushed function: SawToothButton
        function SawToothButtonPushed(app, event)
            app.t= -app.cc*app.T_0:0.01:app.cc*app.T_0;
            yt = app.Amp/2;  % Dc value in Fourier Series
            for k = -app.kk:app.kk
                if k == 0      % For DC Value
                    yt = yt;
                else           % For Even and Odd Harmonics
                    yt = yt + ((1i*app.Amp)/(2*pi*k)).*exp(k*1i*2*pi*app.t/app.T_0);
                end
            end
            plot(app.UIAxes,app.t,yt);
            xlabel(app.UIAxes,'Time (sec)');  ylabel(app.UIAxes,'x(t)');
            title(app.UIAxes,'Sawtooth Wave')
        end

        % Button pushed function: TriangularWaveButton
        function TriangularWaveButtonPushed(app, event)
            app.t= -app.cc*app.T_0:0.01:app.cc*app.T_0;
            yt = app.Amp/2;  % Dc value in Fourier Series
            for k = -app.kk:app.kk
                if k == 0    % For DC Value
                    yt = yt;
                elseif mod(k,2)~=0   % For Odd Harmonics
                    yt = yt + (-2*app.Amp)./(pi*pi*k*k).*exp(k*1i*2*pi*app.t/app.T_0);
                else      % For Even Harmonics
                    yt = yt + 0;
                end
            end
            plot(app.UIAxes,app.t,yt);
            xlabel(app.UIAxes,'Time (sec)');  ylabel(app.UIAxes,'x(t)');
            title(app.UIAxes,'Triangular Wave')
        end

        % Button pushed function: ImpulseTrainButton
        function ImpulseTrainButtonPushed(app, event)
            yt = 0;    % Dc value in Fourier Series
            for k = -app.kk:app.kk
                if k == 0        % For DC value
                    yt = yt;
                else           % For Even and Odd Harmonics
                    yt = yt + exp(k*1i*2*pi*app.t/app.T_0);
                end
            end
            yt = yt./(2*k).*app.Amp;
            plot(app.UIAxes,app.t,yt);
            xlabel(app.UIAxes,'Time (sec)');  ylabel(app.UIAxes,'x(t)');
            title(app.UIAxes,'Impulse Train')
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.EnterAmplitudeButton = uibutton(app.LeftPanel, 'push');
            app.EnterAmplitudeButton.ButtonPushedFcn = createCallbackFcn(app, @EnterAmplitudeButtonPushed, true);
            app.EnterAmplitudeButton.Text = 'Enter Amplitude';
            app.EnterTimePeriodButton = uibutton(app.LeftPanel, 'push');
            app.EnterTimePeriodButton.ButtonPushedFcn = createCallbackFcn(app, @EnterTimePeriodButtonPushed, true);
            app.EnterTimePeriodButton.Text = 'Enter Time Period';
            app.EnterHarmonicsButton = uibutton(app.LeftPanel, 'push');
            app.EnterHarmonicsButton.ButtonPushedFcn = createCallbackFcn(app, @EnterHarmonicsButtonPushed, true);
            app.EnterHarmonicsButton.Text = 'Enter Harmonics';
            app.FullWaveRectifiedButton = uibutton(app.LeftPanel, 'push');
            app.FullWaveRectifiedButton.ButtonPushedFcn = createCallbackFcn(app, @FullWaveRectifiedButtonPushed, true);
            app.FullWaveRectifiedButton.Icon = 'Full Rectified Wave.jpg';
            app.FullWaveRectifiedButton.Text = 'Full-Wave Rectified';
            app.HalfWaveRectifiedButton = uibutton(app.LeftPanel, 'push');
            app.HalfWaveRectifiedButton.ButtonPushedFcn = createCallbackFcn(app, @HalfWaveRectifiedButtonPushed, true);
            app.HalfWaveRectifiedButton.Icon = 'Half Rectified Wave.png';
            app.HalfWaveRectifiedButton.Text = 'Half-Wave Rectified';
            app.RectangularWaveButton = uibutton(app.LeftPanel, 'push');
            app.RectangularWaveButton.ButtonPushedFcn = createCallbackFcn(app, @RectangularWaveButtonPushed, true);
            app.RectangularWaveButton.Icon = 'Rectangular Wave.png';
            app.RectangularWaveButton.Text = 'Rectangular Wave';
            app.SquareWaveButton = uibutton(app.LeftPanel, 'push');
            app.SquareWaveButton.ButtonPushedFcn = createCallbackFcn(app, @SquareWaveButtonPushed, true);
            app.SquareWaveButton.Icon = 'Square Wave.png';
            app.SquareWaveButton.Text = 'Square Wave';
            app.SawToothButton = uibutton(app.LeftPanel, 'push');
            app.SawToothButton.ButtonPushedFcn = createCallbackFcn(app, @SawToothButtonPushed, true);
            app.SawToothButton.Icon = 'Sawtooth Wave.png';
            app.SawToothButton.Text = 'SawTooth';
            app.TriangularWaveButton = uibutton(app.LeftPanel, 'push');
            app.TriangularWaveButton.ButtonPushedFcn = createCallbackFcn(app, @TriangularWaveButtonPushed, true);
            app.TriangularWaveButton.Icon = 'Triangular Wave.png';
            app.TriangularWaveButton.Text = 'Triangular Wave';
            app.ImpulseTrainButton = uibutton(app.LeftPanel, 'push');
            app.ImpulseTrainButton.ButtonPushedFcn = createCallbackFcn(app, @ImpulseTrainButtonPushed, true);
            app.ImpulseTrainButton.Icon = 'Impulse Train.png';
            app.ImpulseTrainButton.Text = 'Impulse Train';
            app.SelecttheWaveformLabel = uilabel(app.LeftPanel);
            app.SelecttheWaveformLabel.Text = 'Select the Waveform';
            app.EntertheParametersLabel = uilabel(app.LeftPanel);
            app.EntertheParametersLabel.Text = 'Enter the Parameters';
            app.CopiesofSignalButton = uibutton(app.LeftPanel, 'push');
            app.CopiesofSignalButton.ButtonPushedFcn = createCallbackFcn(app, @CopiesofSignalButtonPushed, true);
            app.CopiesofSignalButton.Text = 'Copies of Signal';
            app.FunctionGeneratorUsingFourierSeriesCoefficientLabel = uilabel(app.LeftPanel);
            app.FunctionGeneratorUsingFourierSeriesCoefficientLabel.Text = {'Function Generator Using'; 'Fourier Series Coefficient'};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = 'First of all enter the values for all parameter after that select the waveform';
            app.FunctionGeneratorButton = uibutton(app.RightPanel, 'push');
            app.FunctionGeneratorButton.Icon = 'Function_Generator.png';
            app.FunctionGeneratorButton.Text = 'Function Generator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Function_Generator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end